classdef demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DistanceEditFieldLabel  matlab.ui.control.Label
        DistanceEditField       matlab.ui.control.NumericEditField
        GoButton                matlab.ui.control.Button
        ConnectButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        a % Arduino
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            a = app.a;
            function goforward(distance)
                %factor = 530;
                if distance == 0
                    % do nothing
                elseif distance > 0
                    writeDigitalPin(a, 'D5', 0);
                    %pause(distance/factor)
                    pause(distance)
                    writeDigitalPin(a, 'D5', 1);
                else
                    writeDigitalPin(a, 'D6', 0);
                    %pause(-distance/factor)
                    pause(-distance)
                    writeDigitalPin(a, 'D6', 1);
                end
            end
            goforward(app.DistanceEditField.Value)
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
        
        app.a = arduino();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end